classdef gameUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        Rock2            matlab.ui.control.Image
        TitleImage       matlab.ui.control.Image
        GAMEOVERLabel    matlab.ui.control.Label
        Rock1            matlab.ui.control.Image
        StartGameButton  matlab.ui.control.StateButton
        Player           matlab.ui.control.Image
        ScoreEditField   matlab.ui.control.NumericEditField
        ScoreLabel       matlab.ui.control.Label
        Background       matlab.ui.control.Image
        UIAxes           matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % Keeps track of the current game state 
        % 0 = Game over; 1 = Game running
        gameRunning = false; 
        gameTimer;  % Timer for controlling game loop
        rockVelocity = 10;
        nextLevel = 5;

        showRock2 = false;

        %Player properties
        isJumping = false;
        jumpVelocity = 8;
        jumpHeight = 100;
        currentJumpY = 0;
        runnerLegTimer; % Timer for runner leg movement
        didSuccessfulJump = false;  % Flag indicating whether the Player jumped

        % Initialize a variable to keep track of the current image for leg
        % movement
        currentImageIndex = 1;
    end
    
    methods (Access = public)
        
        function obstacleMovement(app) 
            if app.Rock1.Position(1) > app.Background.Position(1) % Check if it's completely off the screen
                app.Rock1.Position(1) = app.Rock1.Position(1) - app.rockVelocity;  % Move left
            else
                app.didSuccessfulJump = false;  % New obstacle - reset jump flag
                app.Rock1.Position(1) = app.Background.Position(3) - app.Rock1.Position(3);  % Reset to the right side of the figure
            end
            
            % If the second rock has been spawned, also begin its movement
            if app.showRock2
                if app.Rock2.Position(1) > app.Background.Position(1) % Check if it's completely off the screen
                    app.Rock2.Position(1) = app.Rock2.Position(1) - app.rockVelocity;  % Move left
                else
                    app.didSuccessfulJump = false;  % New obstacle - reset jump flag
                    app.Rock2.Position(1) = app.Background.Position(3) - app.Rock2.Position(3);  % Reset to the right side of the figure
                end
            end
        end

    end
    
    methods (Access = private)
        
        function setUpGameTimer(app)
            app.gameTimer = timer('ExecutionMode','fixedRate', ...
                'Period', 0.05,  ... %Speed of game loop
                'TimerFcn', @(src, event)gameLoop(app));

            app.runnerLegTimer = timer('ExecutionMode','fixedRate', ...
                'Period', 0.2,  ... %Speed of game loop
                'TimerFcn', @(~,~)runnerLegMovement(app));
        end
        
        function gameLoop(app)
            % Spawns second rock when score = 10 and first rock is halfway
            % across
            if app.ScoreEditField.Value == 10 && ~app.showRock2 && ... 
                   app.Rock1.Position(1) > app.Player.Position(1) + app.Player.Position(3) ... 
                   && app.Rock1.Position(1) <= 500
                app.Rock2.Visible = "on";
                app.showRock2 = true;
            end

            obstacleMovement(app); %Move obstacle

            if app.isJumping % If player is jumping
                updateJump(app);
            end

            if isGameOver(app) % If the game is over
                stopGame(app);
            end

            if isSuccessfulJump(app)
                % Update jump flag and score
                app.didSuccessfulJump = true;   
                app.ScoreEditField.Value = app.ScoreEditField.Value + 1;
            end

            if app.nextLevel == app.ScoreEditField.Value && app.ScoreEditField.Value <= 20
                % When score increments by 5, rock velocity increases by 3
                app.nextLevel = app.nextLevel + 5;
                app.rockVelocity = app.rockVelocity + 3;
            end
        end
        
        function resetGame(app)
            % Reset game elements to initial state
            % Position: [left bottom width height]
            app.Rock1.Position = [958 100 50 30]; % Reset obstacle position
            app.rockVelocity = 10;
            app.isJumping = false;
            app.Player.Position = [100 100 84 106]; % Reset player position
            app.StartGameButton.Text = "Start Game";
            app.ScoreEditField.Value = 0;
            app.GAMEOVERLabel.Visible = "off";
            app.GAMEOVERLabel.Text = "GAME OVER Your Score: ";
            app.nextLevel = 5;
            app.showRock2 = false;
        end

        function success = isSuccessfulJump(app)
            % Successful jump if Rock is behind the Player
            rock1Right = app.Rock1.Position(1) + app.Rock1.Position(3);
            playerLeft = app.Player.Position(1);

             % Add check for if the Player already jumped over rock1
            success = (playerLeft > rock1Right) & ~app.didSuccessfulJump;
            if success
                return;
            end

            % Check if Player jumped over rock2
            if app.showRock2
                rock2Right = app.Rock2.Position(1) + app.Rock2.Position(3);
                success = (playerLeft > rock2Right) & ~app.didSuccessfulJump;
            end
        end

        function gameOver = isGameOver(app) % Check if player collided with obstacle
            % Collision Detection
            % Vertical: Player.bottom <= Rock.top
            % Horizontal: Player.left <= Rock.left && Player.right >= Rock.left ||
            % Player.left <= Rock.right && Player.right >= Rock.right

            rock1Top = app.Rock1.Position(2) + app.Rock1.Position(4);
            rock1Left = app.Rock1.Position(1);
            rock1Right = app.Rock1.Position(1) + app.Rock1.Position(3);
            playerBottom = app.Player.Position(2);
            playerLeft = app.Player.Position(1);
            playerRight = app.Player.Position(1) + app.Player.Position(3);

            x1Collide = (playerLeft <= rock1Left && playerRight >= rock1Left) || ...
                (playerLeft <= rock1Right && playerRight >= rock1Right);
            y1Collide = playerBottom <= rock1Top;
            gameOver = x1Collide & y1Collide;
            if gameOver 
                return; % If there was a collision with rock 1, return early
            end

            % Check collision with rock 2
            if app.showRock2
                rock2Top = app.Rock2.Position(2) + app.Rock2.Position(4);
                rock2Left = app.Rock2.Position(1);
                rock2Right = app.Rock2.Position(1) + app.Rock2.Position(3);

                x1Collide = (playerLeft <= rock2Left && playerRight >= rock2Left) || ...
                    (playerLeft <= rock2Right && playerRight >= rock2Right);
                y1Collide = playerBottom <= rock2Top;
                gameOver = x1Collide & y1Collide;
            end
            
        end

        
        function updateJump(app)
            if app.isJumping 
                newY = app.Player.Position(2) + app.jumpVelocity;
                maxJumpReach = app.currentJumpY + app.jumpHeight;
                if newY > maxJumpReach
                    newY = maxJumpReach;
                    app.jumpVelocity = -app.jumpVelocity; 
                elseif newY < app.currentJumpY
                        newY = app.currentJumpY;
                        app.isJumping = false;
                        app.jumpVelocity = abs(app.jumpVelocity);
                end
                app.Player.Position(2) = newY;
            
            end
        end
        
        function stopGame(app)
            stop(app.gameTimer);  % Stop game timer
            stop(app.runnerLegTimer); % Stop runnerLegTimer
            app.gameRunning = 0;  % Update game state
            app.StartGameButton.Text = "Start Game";  % Update button text
            app.StartGameButton.Value = 0;
            app.Rock1.Visible = "off";  % Hide obstacle
            app.Rock2.Visible = "off";
            app.GAMEOVERLabel.Text = strcat(app.GAMEOVERLabel.Text, num2str(app.ScoreEditField.Value));
            app.GAMEOVERLabel.Visible = "on"; % Display Game Over, Score
        end

        function runnerLegMovement(app)
            % Runner legs running movement
            % Define the paths to the two images of the runner with different leg positions
            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            pathToImage1 = fullfile(pathToMLAPP, 'mickey_mouse.png');
            pathToImage2 = fullfile(pathToMLAPP, 'mickey_mouse_2.png');
            
            % Alternate between two images
            if app.currentImageIndex == 1
                app.Player.ImageSource = pathToImage2;
                app.currentImageIndex = 2;
            else
                app.Player.ImageSource = pathToImage1;
                app.currentImageIndex = 1;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function UIFiguresStartupFcn(app)
            setUpGameTimer(app); %Initialized game timer on component creation "game start"
        end

        % Value changed function: StartGameButton
        function StartButtonPressed(app, event)
            % value is 0 on default/unpressed, 1 when pressed
            app.gameRunning = app.StartGameButton.Value; 

            if ~app.gameRunning 
                stopGame(app);
            else                   
                % If the game is running
                % Display "End Game" button
                focus(app.UIFigure); % move focus away from button to figure 
                resetGame(app); %Resets the game
                app.StartGameButton.Text = "End Game";
                app.GAMEOVERLabel.Visible = "off";
                % Show Images
                app.Player.Visible = "on";
                app.Rock1.Visible = "on";
                start(app.gameTimer); % Start gameTimer
                start(app.runnerLegTimer); % Start runnerLegTimer

                % Make the obstacle move
                obstacleMovement(app);
            end   
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            if(strcmp(event.Key, 'space') && ~app.isJumping)
                app.isJumping = true;
                app.currentJumpY = app.Player.Position(2);
            end       
        end

        % Image clicked function: Background
        function BackgroundImageClicked(app, event)
            if(app.gameRunning && ~app.isJumping)
                app.isJumping = true;
                app.currentJumpY = app.Player.Position(2);
            end
            focus(app.UIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.Visible = 'off';
            app.Background = uiimage(app.UIFigure);
            app.Background.ImageClickedFcn = createCallbackFcn(app, @BackgroundImageClicked, true);
            app.Background.ImageSource = fullfile(pathToMLAPP, 'lightning.gif');
            app.ScoreLabel = uilabel(app.UIFigure);
            app.ScoreLabel.Text = 'Score';
            app.ScoreEditField = uieditfield(app.UIFigure, 'numeric');
            app.Player = uiimage(app.UIFigure);
            app.Player.ImageSource = fullfile(pathToMLAPP, 'mickey_mouse.png');
            app.StartGameButton = uibutton(app.UIFigure, 'state');
            app.StartGameButton.ValueChangedFcn = createCallbackFcn(app, @StartButtonPressed, true);
            app.StartGameButton.Text = 'Start Game';
            app.Rock1 = uiimage(app.UIFigure);
            app.Rock1.Visible = 'off';
            app.Rock1.ImageSource = fullfile(pathToMLAPP, 'rock_obstacle_transparent.png');
            app.GAMEOVERLabel = uilabel(app.UIFigure);
            app.GAMEOVERLabel.Visible = 'off';
            app.GAMEOVERLabel.Text = 'GAME OVER Your Score: ';
            app.TitleImage = uiimage(app.UIFigure);
            app.TitleImage.ImageSource = fullfile(pathToMLAPP, 'game_title.png');
            app.Rock2 = uiimage(app.UIFigure);
            app.Rock2.Visible = 'off';
            app.Rock2.ImageSource = fullfile(pathToMLAPP, 'rock_obstacle_transparent.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gameUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @UIFiguresStartupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end